classdef Run_GD_MMSE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        NumberofparallelprocessesEditFieldLabel  matlab.ui.control.Label
        NumberofparallelprocessesEditField  matlab.ui.control.NumericEditField
        Infolabel   matlab.ui.control.Label
        RunButton   matlab.ui.control.Button
        MenuButton  matlab.ui.control.Button
        BackButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        gotomenu = 1 % Description
    end
    
    methods (Access = private)
        
        function confirmed = popup_show(app)
            fig = app.UIFigure;
            msg = 'Parameter data already found. Do you want to overwrite this data?';
            title = 'Confirm';
            selection = uiconfirm(fig,msg,title, 'Options',{'Overwrite','Cancel'}, 'DefaultOption',2,'CancelOption',2);
            if strcmp(selection,'Overwrite')
                confirmed = 1;
            else
                confirmed = 0;
            end 
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Run GD parameter estimation using MMSE';
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.gotomenu = 0;
            Set_optimization_parameters()
            app.UIFigureCloseRequest
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Implement check on whether script is already running
            [path] = uigetdir('..','Select location to save the output');
            if path == 0
                confirmed = 0;
            else
                confirmed = 1;
            end
            if isfile(fullfile(path,'results_GD.mat'))
                confirmed = app.popup_show();
            end
            if confirmed
                if isfile(fullfile('Output','results_GD_MMSE.mat'))
                    delete(fullfile('Output','results_GD_MMSE.mat'))
                end
                if exist(fullfile('Output','Temp'),'dir')
                    d = dir(fullfile('Output','Temp'));
                    numfiles = size(d,1);
                    for filenumber = 1:numfiles
                        if contains(d(filenumber).name,'Gradient_Desc')
                            delete(fullfile('Output','Temp',d(filenumber).name))
                        end
                    end
                end                
                app.Infolabel.Text = 'Estimating parameters, please wait';
                % Start scripts
                app.RunButton.Enable = 'off';
                app.MenuButton.Enable = 'off';
                app.BackButton.Enable = 'off';
                pause(1)
                load(fullfile('Input','nparts.mat'),'npart')
                nprocs = app.NumberofparallelprocessesEditField.Value;
                system(strcat("bash Run_GD_MMSE_parall.sh ", num2str(npart)," ",num2str(nprocs)))
                merge_res_GD(nprocs)
                if ~path==0
                    filename = fullfile(path,'results_GD_MMSE.mat');
                    copyfile(fullfile('Output','results_GD.mat'),filename);
                end
                app.RunButton.Enable = 'on';
                app.MenuButton.Enable = 'on';
                app.BackButton.Enable = 'on';
                app.Infolabel.Text = 'Finished estimating parameters';
            end
            % Implement 
        end

        % Button pushed function: MenuButton
        function MenuButtonPushed(app, event)
            app.UIFigureCloseRequest()
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.gotomenu
                Main_menu()
            end
            delete(app)            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.NumberofparallelprocessesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofparallelprocessesEditFieldLabel.Text = 'Number of parallel processes';
            app.NumberofparallelprocessesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofparallelprocessesEditField.Limits = [1 Inf];
            app.NumberofparallelprocessesEditField.RoundFractionalValues = 'on';
            app.NumberofparallelprocessesEditField.Value = 1;
            app.Infolabel = uilabel(app.UIFigure);
            app.Infolabel.Text = 'How many parallel processes do you want to run?';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.MenuButton = uibutton(app.UIFigure, 'push');
            app.MenuButton.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.MenuButton.Text = 'Menu';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Run_GD_MMSE

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end